classdef AdjustCoRegVols < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        MainLayout         matlab.ui.container.GridLayout
        ScalingPanel       matlab.ui.container.Panel
        ScalingLayout      matlab.ui.container.GridLayout
        RotationPanel      matlab.ui.container.Panel
        RotationLayout     matlab.ui.container.GridLayout
        TranslationPanel   matlab.ui.container.Panel
        TranslationLayout  matlab.ui.container.GridLayout
        ViewerPanel        matlab.ui.container.Panel
        ViewerLayout       matlab.ui.container.GridLayout
    end

    
    properties (Access = public)
        MovingVolume (:,:,:) % moving binary volume (in transform input coordinate space)
        FixedVolume (:,:,:) % fixed binary volume (in transform output coordinate space)
        MovingCentroid (1,3) double % centroid of moving volume
        Viewer3D images.ui.graphics3d.Viewer3D % viewer for registered volumes
        Moving3DObj images.ui.graphics3d.Volume % moving 3D volume object
        Fixed3DObj images.ui.graphics3d.Volume % fixed 3D volume object
        
        MovingCheckbox (1,1) matlab.ui.control.CheckBox % visibility checkbox for moving 3D volume object
        FixedCheckbox (1,1) matlab.ui.control.CheckBox % visibility checkbox for fixed 3D volume object
        
        SaveButton (1,1) matlab.ui.control.Button

        ScalingSliders (1,3) matlab.ui.control.Slider
        RotationSliders (1,3) matlab.ui.control.Slider
        TranslationSliders (1,3) matlab.ui.control.Slider

        IsoscalingCheckbox (1,1) matlab.ui.control.CheckBox
        
        Transform affinetform3d = affinetform3d()
    end

    methods (Access = public)

        function EnforceIsoscaling(app, new_scaling)
            if nargin < 2
                new_scaling = mean([app.ScalingSliders.Value]);
            end

            for i = 1:length(app.ScalingSliders)
                app.ScalingSliders(i).Value = new_scaling;
            end
        end

        function SetInitialSliderValues(app, tinit)

            app.MovingCentroid = mean(volume_to_points(app.MovingVolume));

            decentering_tform = transltform3d(app.MovingCentroid);
            tinit_after_centering = affinetform3d(tinit.A * decentering_tform.A);
            
            params = decompose_affine_matrix(tinit_after_centering.A);

            for i = 1:3
                app.ScalingSliders(i).Value = params.scaling(i);
                app.RotationSliders(i).Value = params.eulerAngles(i);
                app.TranslationSliders(i).Value = params.translation(i);
            end

            if app.IsoscalingCheckbox.Value
                app.EnforceIsoscaling();
            end

            app.UpdateViewerTransformation();

        end

        function UpdateViewerTransformation(app)

            centering_tform = transltform3d(-app.MovingCentroid);

            S = affinetform3d(diag([app.ScalingSliders.Value, 1]));
            R = rigidtform3d([app.RotationSliders.Value], [0,0,0]);
            T = transltform3d([app.TranslationSliders.Value]);

            app.Transform = affinetform3d(T.A * R.A * S.A * centering_tform.A);
            
            props = app.GetCameraProperties();
            app.Moving3DObj.Transformation = app.Transform;
            drawnow();
            app.SetCameraProperties(props);
        end

        function props = GetCameraProperties(app)
            props = struct( ...
                'CameraPosition', app.Viewer3D.CameraPosition, ...
                'CameraTarget', app.Viewer3D.CameraTarget, ...
                'CameraUpVector', app.Viewer3D.CameraUpVector, ...
                'CameraZoom', app.Viewer3D.CameraZoom ...
            );
        end

        function SetCameraProperties(app, props)
            app.Viewer3D.CameraPosition = props.CameraPosition;
            app.Viewer3D.CameraTarget = props.CameraTarget;
            app.Viewer3D.CameraUpVector = props.CameraUpVector;
            app.Viewer3D.CameraZoom = props.CameraZoom;
        end

    end
    
    methods (Access = private)
        
        function SliderChanged(app, src, data)
            if app.IsoscalingCheckbox.Value
                if any(app.ScalingSliders == src)
                    EnforceIsoscaling(app, data.Value);
                end
            end

            app.UpdateViewerTransformation();
        end

        function IsoscalingChanged(app, ~, data)
            if data.Value
                app.EnforceIsoscaling();
            end
            app.UpdateViewerTransformation();
        end
    
        function CreateSliders(app, liveUpdate)
            labels = {'x','y','z'};
            if liveUpdate
                callback = 'ValueChangingFcn';
            else
                callback = 'ValueChangedFcn';
            end

            app.IsoscalingCheckbox = uicheckbox(app.ScalingLayout, 'Text', 'Isoscaling', ...
                'Value', false, 'ValueChangedFcn', @app.IsoscalingChanged);
            app.IsoscalingCheckbox.Layout.Row = 1;
            app.IsoscalingCheckbox.Layout.Column = 3;

            for i = 1:3
                sliderLabel = uilabel(app.ScalingLayout);
                sliderLabel.HorizontalAlignment = 'center';
                sliderLabel.VerticalAlignment = 'center';
                sliderLabel.FontSize = 14;
                sliderLabel.Interpreter = 'tex';
                sliderLabel.Text = ['S_' labels{i}];
                sliderLabel.Layout.Row = i+1;
                sliderLabel.Layout.Column = 1;
                app.ScalingSliders(i) = uislider(app.ScalingLayout);
                app.ScalingSliders(i).Layout.Row = i+1;
                app.ScalingSliders(i).Layout.Column = [2,3];
                app.ScalingSliders(i).Value = 1;
                app.ScalingSliders(i).Limits = [0, 3];
                app.ScalingSliders(i).(callback) = @app.SliderChanged;
            end
            
            for i = 1:3
                sliderLabel = uilabel(app.RotationLayout);
                sliderLabel.HorizontalAlignment = 'center';
                sliderLabel.VerticalAlignment = 'center';
                sliderLabel.FontSize = 14;
                sliderLabel.Interpreter = 'tex';
                sliderLabel.Text = ['R_' labels{i}];
                sliderLabel.Layout.Row = i;
                sliderLabel.Layout.Column = 1;
                app.RotationSliders(i) = uislider(app.RotationLayout);
                app.RotationSliders(i).Layout.Row = i;
                app.RotationSliders(i).Layout.Column = 2;
                app.RotationSliders(i).Value = 0;
                app.RotationSliders(i).Limits = [-180, 180];
                app.RotationSliders(i).MajorTicks = linspace(...
                    app.RotationSliders(i).Limits(1), ...
                    app.RotationSliders(i).Limits(2), ...
                    9);
                app.RotationSliders(i).(callback) = @app.SliderChanged;
            end

            for i = 1:3
                sliderLabel = uilabel(app.TranslationLayout);
                sliderLabel.HorizontalAlignment = 'center';
                sliderLabel.VerticalAlignment = 'center';
                sliderLabel.FontSize = 14;
                sliderLabel.Interpreter = 'tex';
                sliderLabel.Text = ['T_' labels{i}];
                sliderLabel.Layout.Row = i;
                sliderLabel.Layout.Column = 1;
                app.TranslationSliders(i) = uislider(app.TranslationLayout);
                app.TranslationSliders(i).Layout.Row = i;
                app.TranslationSliders(i).Layout.Column = 2;
                app.TranslationSliders(i).Value = 0;
                app.TranslationSliders(i).Limits = [0, size(app.MovingVolume, i)];
                app.TranslationSliders(i).(callback) = @app.SliderChanged;
            end
        end

        function SaveButtonPushed(app, ~, ~)
            selected = uiconfirm(app.UIFigure, ...                       % parent → attaches to app
                'Save current transformation to the workspace as "tfinal"?', ...
                'Confirm Save', ...
                'Options',       {'Save','Cancel'}, ...
                'DefaultOption', 'Save', ...
                'CancelOption',  'Cancel', ...
                'Icon',          'question');

            if strcmpi(selected, 'Save')
                assignin('base', 'tfinal', app.Transform);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, movingVol, fixedVol, tinit, varargin)
            assert(islogical(movingVol), 'Moving volume must be logical (binary).');
            assert(islogical(fixedVol), 'Fixed volume must be logical (binary).');
            
            p = inputParser;
            addParameter(p, 'LiveUpdate', false, @(b) isscalar(b) && islogical(b));
            addParameter(p, 'MaxAlpha', 0.05, @(a) isscalar(a) && isnumeric(a));
            
            % Parse input arguments
            parse(p, varargin{:});
            
            liveUpdate = p.Results.LiveUpdate;
            maxAlpha = p.Results.MaxAlpha;

            app.MovingVolume = movingVol;
            app.FixedVolume = fixedVol;

            app.Viewer3D = viewer3d('Parent', app.ViewerLayout, ...
                'BackgroundColor', app.MainLayout.BackgroundColor, ...
                'BackgroundGradient', false, ...
                'Lighting', 'off', 'RenderingQuality', 'low');
            app.Viewer3D.Layout.Row = [1, 3];
            app.Viewer3D.Layout.Column = [1, 3];

            app.Moving3DObj = volshow(app.MovingVolume, ...
                'Parent', app.Viewer3D, ...
                'DataLimits', [0, 1], ...
                'Colormap', [gray(2); 0.7, 0, 0.7], ...
                'Alphamap', linspace(0, maxAlpha, 256)');
            app.Fixed3DObj = volshow(app.FixedVolume, ...
                'Parent', app.Viewer3D, ...
                'DataLimits', [0, 1], ...
                'Colormap', [gray(2); 0, 0.7, 0], ...
                'Alphamap', linspace(0, maxAlpha, 256)');

            app.MovingCheckbox = uicheckbox(app.ViewerLayout, 'Text', 'Moving', ...
                'FontColor', [0.7, 0, 0.7], 'FontSize', 14, 'Value', true, ...
                'ValueChangedFcn', {@(app, src, obj) set(obj, 'Visible', src.Value), app.Moving3DObj});
            app.MovingCheckbox.Layout.Row = 1;
            app.MovingCheckbox.Layout.Column = 1;

            app.FixedCheckbox = uicheckbox(app.ViewerLayout, 'Text', 'Fixed', ...
                'FontColor', [0, 0.7, 0], 'FontSize', 14, 'Value', true, ...
                'ValueChangedFcn', {@(app, src, obj) set(obj, 'Visible', src.Value), app.Fixed3DObj});
            app.FixedCheckbox.Layout.Row = 2;
            app.FixedCheckbox.Layout.Column = 1;
            
            app.SaveButton = uibutton(app.ViewerLayout, 'push', ...
                'Text', 'Save', 'FontSize', 14);
            app.SaveButton.Layout.Row = 4;
            app.SaveButton.Layout.Column = 3;
            app.SaveButton.ButtonPushedFcn = @app.SaveButtonPushed;

            app.CreateSliders(liveUpdate);
            
            drawnow();
            
            app.SetInitialSliderValues(tinit);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainLayout = uigridlayout(app.UIFigure);
            app.ViewerPanel = uipanel(app.MainLayout);
            app.ViewerPanel.Title = 'Viewer';
            app.ViewerLayout = uigridlayout(app.ViewerPanel);
            app.TranslationPanel = uipanel(app.MainLayout);
            app.TranslationPanel.Title = 'Translation';
            app.TranslationLayout = uigridlayout(app.TranslationPanel);
            app.RotationPanel = uipanel(app.MainLayout);
            app.RotationPanel.Title = 'Rotation';
            app.RotationLayout = uigridlayout(app.RotationPanel);
            app.ScalingPanel = uipanel(app.MainLayout);
            app.ScalingPanel.Title = 'Scaling';
            app.ScalingLayout = uigridlayout(app.ScalingPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdjustCoRegVols(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end