classdef DualOrthoViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ControlPointSelectorUIFigure  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LoadPointsButton              matlab.ui.control.Button
        UpdateButton                  matlab.ui.control.Button
        ResultsPanel                  matlab.ui.container.Panel
        ControlPointsListBox          matlab.ui.control.ListBox
        ControlPointsLabel            matlab.ui.control.Label
        AddButton                     matlab.ui.control.Button
        MovingPanel                   matlab.ui.container.Panel
        MovingGridLayout              matlab.ui.container.GridLayout
        MovingOrthoPanel              matlab.ui.container.Panel
        Moving3DPanel                 matlab.ui.container.Panel
        FixedPanel                    matlab.ui.container.Panel
        FixedGridLayout               matlab.ui.container.GridLayout
        FixedOrthoPanel               matlab.ui.container.Panel
        Fixed3DPanel                  matlab.ui.container.Panel
    end

    
    properties (Access = public)
        MovingVolume (:,:,:) double % volume in transform input coordinate space
        MovingVolumeBin (:,:,:) double % thresholded moving volume
        FixedVolume (:,:,:) double % volume in transform output coordinate space
        FixedVolumeBin (:,:,:) double % thresholded fixed volume
        MovingOrthoViewer orthosliceViewer % orthosliceViewer for moving volume
        FixedOrthoViewer orthosliceViewer % orthosliceViewer for fixed volume
        Moving3DViewer images.ui.graphics3d.Viewer3D % viewer3d for moving volume
        Fixed3DViewer images.ui.graphics3d.Viewer3D % viewer3d for fixed volume
        ResultsViewer images.ui.graphics3d.Viewer3D % viewer for registered volumes
        MovingThreshold (1,1) double % numeric threshold for visualization of moving volume
        FixedThreshold (1,1) double % numeric threshold for visualization of fixed volume
        Moving3DObj images.ui.graphics3d.Volume % moving 3D volume below orthoslice viewer
        Fixed3DObj images.ui.graphics3d.Volume % fixed 3D volume below orthoslice viewer
        Moving3DObjReg images.ui.graphics3d.Volume % moving 3D volume in coreg viewer
        Fixed3DObjReg images.ui.graphics3d.Volume % fixed 3D volume in coreg viewer
        MovingControlPoints (:,3) double
        FixedControlPoints (:,3) double
        Transformation affinetform3d
        SaveDir (1,:) char
        SaveFile (1,:) char
    end
    
    methods (Access = private)
        function processControlPoints(app, doSave)
            if nargin < 2
                doSave = true;
            end

            movingPoints = app.MovingControlPoints;
            fixedPoints = app.FixedControlPoints;
            assert(height(movingPoints) == height(fixedPoints));
            npts = height(movingPoints);
            save_vars = {'movingPoints', 'fixedPoints', 'npts'};
            
            if npts >= 3
                [tform, inliers] = estgeotform3d(movingPoints, fixedPoints, ...
                    'similarity', 'MaxDistance', realmax('double'));
                assert(all(inliers));
                app.Transformation = tform;
                app.Moving3DObjReg.Transformation = app.Transformation;
                save_vars = [save_vars, {'tform'}];
                drawnow();
            end
            if doSave
                save(fullfile(app.SaveDir, app.SaveFile), save_vars{:});
            end
        end
    end

    methods (Access = public)
        function update3DCursors(app)
            r = 3;
            rrange = -r:r;
            [x,y,z] = ndgrid(rrange);
            kern = sqrt(x.^2 + y.^2 + z.^2) <= r;

            movingInds = mat2cell(app.MovingOrthoViewer.SliceNumbers + rrange', length(rrange), ones(3, 1));
            movingInds(1:2) = movingInds([2,1]); % convert cols, rows --> X, Y
            movingWithCursor = app.MovingVolumeBin;
            movingWithCursor(movingInds{:}) = min(movingWithCursor(movingInds{:}) + kern * 2, 2);
            app.Moving3DObj.Data = movingWithCursor;
            drawnow();

            fixedInds = mat2cell(app.FixedOrthoViewer.SliceNumbers + rrange', length(rrange), ones(3, 1));
            fixedInds(1:2) = fixedInds([2,1]); % convert cols, rows --> X, Y
            fixedWithCursor = app.FixedVolumeBin;
            fixedWithCursor(fixedInds{:}) = min(fixedWithCursor(fixedInds{:}) + kern * 2, 2);
            app.Fixed3DObj.Data = fixedWithCursor;
            drawnow();
            
        end
    end

    methods (Static)
        function cleaned = cleanVolume(raw, name)
            new_val = -1000;
            cleaned = double(raw);
            if any(isinf(cleaned), 'all')
                cleaned(isinf(cleaned)) = new_val;
                disp(['Replaced infinite values in ' name ' volume with ' num2str(new_val)]);
            elseif any(isnan(cleaned), 'all')
                cleaned(isnan(cleaned)) = new_val;
                disp(['Replaced NaN values in ' name ' volume with ' num2str(new_val)]);
            end
        end

        function isvalid = isvalidclim(lims)
            isvalid = isnumeric(lims) && numel(lims) == 2 && lims(2) > lims(1);
        end

        function hiddenAxes = tailorOrthoViewer(os)
            sliceLabels = findall(os.Parent, 'Type', 'Text', 'Tag', 'SliceNumber');
            sliceLabels.Units = 'normalized';
            sliceLabels.Position = [0.05, 0.95, 0];
            sliceLabels.FontSize = 14;
            hiddenAxes = sliceLabels.Parent;
            cb = colorbar(hiddenAxes);
            
            hiddenAxes.Colormap = os.Colormap;
            hiddenAxes.CLim = os.DisplayRange;
            hiddenAxes.Units = 'normalized';
            cb.Units = 'normalized';
            cb.Position = [
                hiddenAxes.Position(1) + hiddenAxes.Position(3) * 0.6, ...
                hiddenAxes.Position(2) + hiddenAxes.Position(4) * 0.25, ...
                hiddenAxes.Position(3) * 0.1, ...
                hiddenAxes.Position(4) * 0.7, ...
            ];
            hiddenAxes.Units = 'pixels';

            crosshairs = findall(os.Parent, 'Type', 'images.roi.Crosshair');
            for i = 1:length(crosshairs)
                crosshairs(i).Color = 'r';
                crosshairs(i).LineWidth = 0.5;
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, movingVol, fixedVol, varargin)
            app.ControlPointsListBox.Items = {};

            app.SaveFile = ['reg3d_' char(datetime('now', 'Format', 'yyyyMMdd_HHmmss'))];
            
            finite_moving_vals = movingVol(~isnan(movingVol) & ~isinf(movingVol));
            default_moving_clim = [min(finite_moving_vals), max(finite_moving_vals)];
            finite_fixed_vals = fixedVol(~isnan(fixedVol) & ~isinf(fixedVol));
            default_fixed_clim = [min(finite_fixed_vals), max(finite_fixed_vals)];
            
            % Define input parser
            p = inputParser;
            addParameter(p, 'MovingDisplayRange', default_moving_clim, @app.isvalidclim);
            addParameter(p, 'FixedDisplayRange', default_fixed_clim,  @app.isvalidclim);
            addParameter(p, 'MovingColormap', gray(256), @isnumeric);
            addParameter(p, 'FixedColormap', gray(256), @isnumeric);
            addParameter(p, 'MovingThreshold', 0.5, @isnumeric);
            addParameter(p, 'FixedThreshold', 0.5, @isnumeric);
            addParameter(p, 'SaveDir', './RegistrationOutput', @ischar);
            
            % Parse input arguments
            parse(p, varargin{:});

            app.SaveDir = p.Results.SaveDir;
            if ~exist(app.SaveDir, 'dir')
                mkdir(app.SaveDir);
            end

            app.MovingVolume = app.cleanVolume(movingVol, 'moving');
            app.FixedVolume = app.cleanVolume(fixedVol, 'fixed');

            app.MovingThreshold = p.Results.MovingThreshold;
            app.FixedThreshold = p.Results.FixedThreshold;

            app.MovingVolumeBin = double(app.MovingVolume > app.MovingThreshold);
            app.FixedVolumeBin = double(app.FixedVolume > app.FixedThreshold);

            app.MovingPanel.Title = sprintf('Moving Volume (%dx%dx%d)', size(app.MovingVolume));
            app.FixedPanel.Title = sprintf('Fixed Volume (%dx%dx%d)', size(app.FixedVolume));

            app.MovingOrthoViewer = orthosliceViewer(app.MovingVolume, ...
                'Parent', app.MovingOrthoPanel, ...
                'DisplayRange', p.Results.MovingDisplayRange, ...
                'DisplayRangeInteraction', 'off', ...
                'Colormap', p.Results.MovingColormap ...
            );
            app.tailorOrthoViewer(app.MovingOrthoViewer);

            app.Moving3DViewer = viewer3d('Parent', app.Moving3DPanel, ...
                'BackgroundColor', 0.94*ones(1,3), 'BackgroundGradient', false);
            app.Moving3DViewer.Units = 'normalized';
            app.Moving3DViewer.Position = [0,0,1,1];
            app.Moving3DObj = volshow(app.MovingVolumeBin, ...
                'Parent', app.Moving3DViewer, ...
                'DataLimits', [0, 2], ...
                'Colormap', [gray(2); 1,0,0]);

            app.FixedOrthoViewer = orthosliceViewer(app.FixedVolume, ...
                'Parent', app.FixedOrthoPanel, ...
                'DisplayRange', p.Results.FixedDisplayRange, ...
                'DisplayRangeInteraction', 'off', ...
                'Colormap', p.Results.FixedColormap ...
            );
            app.tailorOrthoViewer(app.FixedOrthoViewer);
            
            app.Fixed3DViewer = viewer3d('Parent', app.Fixed3DPanel, ...
                'BackgroundColor', 0.94*ones(1,3), 'BackgroundGradient', false);
            app.Fixed3DViewer.Units = 'normalized';
            app.Fixed3DViewer.Position = [0,0,1,1];
            app.Fixed3DObj = volshow(app.FixedVolumeBin, ...
                'Parent', app.Fixed3DViewer, ...
                'DataLimits', [0, 2], ...
                'Colormap', [gray(2); 1,0,0]);

            drawnow();


            app.ResultsViewer = viewer3d('Parent', app.ResultsPanel, ...
                'BackgroundColor', 0.94*ones(1,3), 'BackgroundGradient', false);

            app.Moving3DObjReg = volshow(app.MovingVolume, 'Parent', app.ResultsViewer);
            app.Fixed3DObjReg = volshow(app.FixedVolume, 'Parent', app.ResultsViewer);

            drawnow();

            app.Moving3DObjReg.Colormap = p.Results.MovingColormap;
            app.Moving3DObjReg.DataLimits = p.Results.MovingDisplayRange;
            app.Fixed3DObjReg.Colormap = p.Results.FixedColormap;
            app.Fixed3DObjReg.DataLimits = p.Results.FixedDisplayRange;
            app.Fixed3DObjReg.Alphamap = linspace(0, 0.2, 256)';

            app.Transformation = app.Moving3DObjReg.Transformation;

        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            if any(all(app.MovingControlPoints == app.MovingOrthoViewer.SliceNumbers, 2)) ...
                || any(all(app.FixedControlPoints == app.FixedOrthoViewer.SliceNumbers, 2))
                warning('Not a new point in both spaces.');
            end

            pointString = sprintf('Point:\n[%d, %d, %d]\n[%d, %d, %d]', ...
                app.MovingOrthoViewer.SliceNumbers, app.FixedOrthoViewer.SliceNumbers);
            disp(pointString);
            app.ControlPointsListBox.Items{end+1} = pointString;
            app.MovingControlPoints(end+1,:) = app.MovingOrthoViewer.SliceNumbers;
            app.FixedControlPoints(end+1,:) = app.FixedOrthoViewer.SliceNumbers;
            app.processControlPoints(true);

        end

        % Double-clicked callback: ControlPointsListBox
        function ControlPointsListBoxDoubleClicked(app, event)
            itemNum = event.InteractionInformation.Item;
            pointString = event.Source.Items{itemNum};
            allSlices = sscanf(pointString, 'Point:\n[%d, %d, %d]\n[%d, %d, %d]');
            movingSlices = allSlices(1:3)';
            fixedSlices = allSlices(4:6)';
            app.MovingOrthoViewer.SliceNumbers = movingSlices;
            app.FixedOrthoViewer.SliceNumbers = fixedSlices;
            app.update3DCursors();
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.update3DCursors();
        end

        % Button pushed function: LoadPointsButton
        function LoadPointsButtonPushed(app, event)
            [filename, fileloc] = uigetfile(fullfile(app.SaveDir, '*.mat'));
            loadPath = fullfile(fileloc, filename);
            loadData = load(loadPath);
            app.MovingControlPoints = loadData.movingPoints;
            app.FixedControlPoints = loadData.fixedPoints;
            app.ControlPointsListBox.Items = {};
            for i = 1:height(loadData.movingPoints)
                pointString = sprintf('Point:\n[%d, %d, %d]\n[%d, %d, %d]', ...
                   loadData.movingPoints(i,:), loadData.fixedPoints(i,:));
                app.ControlPointsListBox.Items{end+1} = pointString;
            end
            app.processControlPoints(false);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ControlPointSelectorUIFigure = uifigure('Visible', 'off');
            app.ControlPointSelectorUIFigure.Name = 'Control Point Selector';
            app.GridLayout = uigridlayout(app.ControlPointSelectorUIFigure);
            app.FixedPanel = uipanel(app.GridLayout);
            app.FixedPanel.Title = 'Fixed Volume';
            app.FixedGridLayout = uigridlayout(app.FixedPanel);
            app.Fixed3DPanel = uipanel(app.FixedGridLayout);
            app.FixedOrthoPanel = uipanel(app.FixedGridLayout);
            app.MovingPanel = uipanel(app.GridLayout);
            app.MovingPanel.Title = 'Moving Volume';
            app.MovingGridLayout = uigridlayout(app.MovingPanel);
            app.Moving3DPanel = uipanel(app.MovingGridLayout);
            app.MovingOrthoPanel = uipanel(app.MovingGridLayout);
            app.AddButton = uibutton(app.GridLayout, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add Point';
            app.ControlPointsLabel = uilabel(app.GridLayout);
            app.ControlPointsLabel.Text = {'Control'; 'Points'};
            app.ControlPointsListBox = uilistbox(app.GridLayout);
            app.ControlPointsListBox.Items = {''};
            app.ControlPointsListBox.DoubleClickedFcn = createCallbackFcn(app, @ControlPointsListBoxDoubleClicked, true);
            app.ControlPointsListBox.Value = '';
            app.ResultsPanel = uipanel(app.GridLayout);
            app.ResultsPanel.Title = 'Results';
            app.UpdateButton = uibutton(app.GridLayout, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.LoadPointsButton = uibutton(app.GridLayout, 'push');
            app.LoadPointsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPointsButtonPushed, true);
            app.LoadPointsButton.Text = 'Load Points';
            % Show the figure after all components are created
            app.ControlPointSelectorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DualOrthoViewer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ControlPointSelectorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ControlPointSelectorUIFigure)
        end
    end
end